classdef Group13 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        SelectImageButton  matlab.ui.control.Button
        UIAxes_3           matlab.ui.control.UIAxes
        UIAxes_2           matlab.ui.control.UIAxes
        UIAxes             matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        selectedImage % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SelectImageButton
        function SelectImageButtonPushed(app, event)
            [file, path] = uigetfile({'*.jpg;*.png;*.bmp;*.pgm;*.gif', 'Image Files (*.jpg, *.png, *.bmp, *.pgm, *.gif)'; '*.*', 'All Files (*.*)'}, 'Bir görüntü seçin');
            
            if isequal(file, 0)
                return;
            else
                fullFilePath = fullfile(path, file);
                app.selectedImage = imread(fullFilePath);
                %Call the normalizing function
                normalizedImg=mynormalize(app.selectedImage);
            end

            HSI= rgbtohsi(normalizedImg);

            Hue=HSI(:,:,1);
            Saturation=HSI(:,:,2);
            Intensity=HSI(:,:,3);

            imshow(Hue, 'Parent', app.UIAxes);
            imshow(Saturation, 'Parent', app.UIAxes_2);
            imshow(Intensity, 'Parent', app.UIAxes_3);

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Hue')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Saturation')
            app.UIAxes_2.XTick = [];
            app.UIAxes_2.YTick = [];
            app.UIAxes_3 = uiaxes(app.UIFigure);
            title(app.UIAxes_3, 'Intensity')
            app.UIAxes_3.XTick = [];
            app.UIAxes_3.YTick = [];
            app.SelectImageButton = uibutton(app.UIFigure, 'push');
            app.SelectImageButton.ButtonPushedFcn = createCallbackFcn(app, @SelectImageButtonPushed, true);
            app.SelectImageButton.Text = 'Select Image';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Group13

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end